classdef testappdesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        pushbutton4         matlab.ui.control.Button
        pushbutton5         matlab.ui.control.Button
        pushbutton2         matlab.ui.control.Button
        pushbutton1         matlab.ui.control.Button
        ViewButtonGroup     matlab.ui.container.ButtonGroup
        RadioButton3D       matlab.ui.control.RadioButton
        RadioButtonXY       matlab.ui.control.RadioButton
        RadioButtonYZ       matlab.ui.control.RadioButton
        RadioButtonXZ       matlab.ui.control.RadioButton
        Label_11            matlab.ui.control.Label
        Z0EditField         matlab.ui.control.EditField
        Z0EditFieldLabel    matlab.ui.control.Label
        Y0EditField         matlab.ui.control.EditField
        Y0EditFieldLabel    matlab.ui.control.Label
        X0EditField         matlab.ui.control.EditField
        X0EditFieldLabel    matlab.ui.control.Label
        N_KnotEditField     matlab.ui.control.EditField
        Label_10            matlab.ui.control.Label
        ZminEditField       matlab.ui.control.EditField
        ZminEditFieldLabel  matlab.ui.control.Label
        ZmaxEditField       matlab.ui.control.NumericEditField
        ZmaxEditFieldLabel  matlab.ui.control.Label
        YmaxEditField       matlab.ui.control.EditField
        YmaxEditFieldLabel  matlab.ui.control.Label
        YminEditField       matlab.ui.control.EditField
        YminEditFieldLabel  matlab.ui.control.Label
        XminEditField       matlab.ui.control.EditField
        XminEditFieldLabel  matlab.ui.control.Label
        XmaxEditField       matlab.ui.control.NumericEditField
        XmaxEditFieldLabel  matlab.ui.control.Label
        CurrentEditField    matlab.ui.control.EditField
        Label_9             matlab.ui.control.Label
        NCoilEditField      matlab.ui.control.EditField
        Label_8             matlab.ui.control.Label
        RadiusEditField     matlab.ui.control.EditField
        EditField_2Label    matlab.ui.control.Label
        LengthEditField     matlab.ui.control.NumericEditField
        Label_7             matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Label               matlab.ui.control.Label
        pushbutton3         matlab.ui.control.Button
        RightPanel          matlab.ui.container.Panel
        ReadDataButton      matlab.ui.control.Button
        Image2              matlab.ui.control.Image
        Image               matlab.ui.control.Image
        pushbutton10        matlab.ui.control.Button
        pushbutton9         matlab.ui.control.Button
        TextArea            matlab.ui.control.TextArea
        TextAreaLabel       matlab.ui.control.Label
        pushbutton8         matlab.ui.control.Button
        pushbutton7         matlab.ui.control.Button
        NStepEditField      matlab.ui.control.EditField
        Label_15            matlab.ui.control.Label
        dREditField         matlab.ui.control.EditField
        Label_14            matlab.ui.control.Label
        PhiEditField        matlab.ui.control.EditField
        Label_13            matlab.ui.control.Label
        ModREditField       matlab.ui.control.EditField
        EditField_7Label    matlab.ui.control.Label
        Label_12            matlab.ui.control.Label
        X00Label            matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Radius;
        Solenoid_Length  
        N_Coil 
        I
        Xmin;
        Xmax
        Ymin
        Ymax
        Zmin
        Zmax
        N_Knot
        X0
        Y0
        Z0
        Mod_R
        Phi
        dR
        N_Step
        % Данные для построения
        A2D     % для контурных карт
        Xmesh     % для 3D-срезов
        Ymesh
        Zmesh
        AxData    % векторы поля
        AyData
        AzData
        R         % радиусы для графика Mh
        Mh       % значения M_h
        Mode     % режим (1 или 2)
        y1
        z1
        a1
        x2
        z2
        a2
        x3
        y3
        a3
        x4
        y4
        z4
        a4
        ax
        ay
        az
    end
    
    methods (Access = private)
        
        
                % Считывание данных из полей ввода
        function readData(app)
            app.Radius         = str2double(app.RadiusEditField.Value);
            app.Solenoid_Length= app.LengthEditField.Value;
            app.N_Coil         = str2double(app.NCoilEditField.Value);
            app.I              = str2double(app.CurrentEditField.Value);
            app.Xmin           = str2double(app.XminEditField.Value);
            app.Xmax           = app.XmaxEditField.Value;
            app.Ymin           = str2double(app.YminEditField.Value);
            app.Ymax           = str2double(app.YmaxEditField.Value);
            app.Zmin           = str2double(app.ZminEditField.Value);
            app.Zmax           = app.ZmaxEditField.Value;
            app.N_Knot         = str2double(app.N_KnotEditField.Value);
            app.X0             = str2double(app.X0EditField.Value);
            app.Y0             = str2double(app.Y0EditField.Value);
            app.Z0             = str2double(app.Z0EditField.Value);
            app.Mod_R          = str2double(app.ModREditField.Value);
            app.Phi            = str2double(app.PhiEditField.Value);
            app.dR             = str2double(app.dREditField.Value);
            app.N_Step         = str2double(app.NStepEditField.Value);
            
        end
        
        function z = Bx(app, x0, y0, z0, a, h, N, I)
            phi = linspace(0, 2*pi*N, 200);
                f = I * ((z0 - phi*h/(2*pi))*a .* cos(phi) - h/(2*pi)*(y0 - a*sin(phi))) ./ ...
                    ((x0^2 + y0^2 + z0^2 + a^2 + (h/(2*pi)*phi).^2 - 2*(x0*a*cos(phi) + a*y0*sin(phi) + (h/(2*pi))*z0*phi)).^(3/2));
            z = trapz(phi, f);
        end
        function z = By(app, x0, y0, z0, a, h, N, I)
            phi = linspace(0, 2*pi*N, 200);
            f = I * ((x0 - a*cos(phi))*h/(2*pi) + a*sin(phi).*(z0 - phi*h/(2*pi))) ./ ...
                ((x0^2 + y0^2 + z0^2 + a^2 + (h/(2*pi)*phi).^2 - 2*(x0*a*cos(phi) + a*y0*sin(phi) + (h/(2*pi))*z0*phi)).^(3/2));
            z = trapz(phi, f);
        end
        function z = Bz(app, x0, y0, z0, a, h, N, I)
            phi = linspace(0, 2*pi*N, 200);
            f = I * ((y0 - a*sin(phi))*a.*sin(phi) + a*cos(phi).*(x0 - a*cos(phi))) ./ ...
                ((x0^2 + y0^2 + z0^2 + a^2 + (h/(2*pi)*phi).^2 - 2*(x0*a*cos(phi) + a*y0*sin(phi) + (h/(2*pi))*z0*phi)).^(3/2));
            z = trapz(phi, f);
        end
        function z = Ax(app, x0, y0, z0, a, h, N, I)
            phi = 0:pi/50 :2*pi*N;
            f = (-I*(a*sin(phi)) ./ ((x0 - a*cos(phi)).^2 + (y0 - a*sin(phi)).^2 + (z0 - h/(2*pi)*phi).^2).^0.5);
            z = trapz(phi, f);
        end
        function z = Ay(app, x0, y0, z0, a, h, N, I)
            phi = 0:pi/50 :2*pi*N;
            f = I*(a*cos(phi)) ./ ((x0 - a*cos(phi)).^2 + (y0 - a*sin(phi)).^2 + (z0 - h/(2*pi)*phi).^2).^0.5;
            z = trapz(phi, f);
        end
        function z = Az(app, x0, y0, z0, a, h, N, I)
            phi = 0:pi/50 :2*pi*N;
            f = (I*h/(2*pi)) ./ ((x0 - a*cos(phi).^2 + (y0 - a*sin(phi)).^2) + (z0 - h/(2*pi)*phi).^2).^0.5;
            z = trapz(phi, f);
        end
        
        function startupFcn(app)
            % Отключаем кнопки, пока не прочитаны данные
            app.pushbutton3.Enable   = 'off';
            app.pushbutton2.Enable   = 'off';
            app.pushbutton4.Enable   = 'off';
            app.pushbutton1.Enable   = 'off';
            app.pushbutton5.Enable   = 'off';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: pushbutton3
        function pushbutton3Pushed(app, event)
            app.readData
            % НАПРЯЖЕННОСТЬ В ПЛОСКОСТИ YoZ
              if (app.RadioButtonXZ.Value ==1)
                if ~isempty(app.y1)
                    app.y1 = real(app.y1);
                    app.z1 = real(app.z1);
                    app.a1 = real(app.a1);
                contour(app.UIAxes,app.y1,app.z1,app.a1,17);
                app.UIAxes.XAxis.FontSize = 20; % Только ось X
                app.UIAxes.YAxis.FontSize = 20;
                hold on
                X1=-app.Radius/2;Y1=0;
                X2=app.Radius/2;Y2=0;
                X3=app.Radius/2;Y3=app.Solenoid_Length;
                X4=-app.Radius/2;Y4=app.Solenoid_Length;
                line(app.UIAxes,[X1,X2,X3,X4,X1],[Y1,Y2,Y3,Y4,Y1])
                hold off
                end
              end
              % НАПРЯЖЕННОСТЬ В ПЛОСКОСТИ XoZ
              if (app.RadioButtonYZ.Value ==1)
                if ~isempty(app.x2) %x2~=[]
                  app.x2 = real(app.x2);
                  app.z2 = real(app.z2);
                  app.a2 = real(app.a2);
                contour(app.UIAxes,app.x2,app.z2,app.a2,17);
                hold on
                X1=-app.Radius/2;Y1=0;
                X2=app.Radius/2;Y2=0;
                X3=app.Radius/2;Y3=app.Solenoid_Length;
                X4=-app.Radius/2;Y4=app.Solenoid_Length;
                line(app.UIAxes,[X1,X2,X3,X4,X1],[Y1,Y2,Y3,Y4,Y1])
                hold off
                end
              end
              % НАПРЯЖЕННОСТЬ В ПЛОСКОСТИ XoY
              if (app.RadioButtonXY.Value == 1)
                if ~isempty(app.x3) % x3~=[]
                  
                app.x3 = real(app.x3);
                app.y3 = real(app.y3);
                app.a3 = real(app.a3);
                hold on
                contour(app.UIAxes,app.x3,app.y3,app.a3,17);
                alpha=0:pi/100:2*pi;
                plot(app.Radius*cos(alpha),app.Radius*sin(alpha))
                hold off
                end
              end
              % НАПРЯЖЕННОСТЬ В ПРОСТРАНСТВЕ
              if (app.RadioButton3D.Value == 1)
                if ~isempty(app.x4) % x4~=[]
                  app.x4 = real(app.x4);
                  app.y4 = real(app.y4);
                  app.z4 = real(app.z4);
                  app.a4 = real(app.a4);
                  hsurfaces = slice(app.UIAxes,app.x4,app.y4,app.z4,app.a4,app.Xmax,app.Ymax,app.Ymin);
                   set(hsurfaces,'FaceColor','interp','EdgeColor','none')
                  hcont = ...
                        contourslice(app.UIAxes,app.x4,app.y4,app.z4,app.a4,app.Xmax,app.Ymax,app.Ymin);
                        set(hcont,'EdgeColor',[.7,.7,.7],'LineWidth',.5)
                  [sx,sy,sz] = meshgrid(app.Xmin:0.1:app.Xmax,app.Ymin:0.1:app.Ymax,app.Zmin);
             hlines = streamline(app.UIAxes,app.x4,app.y4,app.z4,app.ax,app.ay,app.az,sx,sy,sz);
             set(hlines,'LineWidth',2,'Color','r')
             view(3)
             daspect([2,2,1])
             axis tight
            
             % [x, y, z] = meshgrid(1:1:app.N_Knot, 1:1:app.N_Knot, 1:1:app.N_Knot);
             % quiver3(x,y,z,app.x4, app.y4, app.z4 ,'r');
            end
           end
           
        end

        % Button pushed function: pushbutton2
        function pushbutton2Pushed(app, event)
            app.readData
            i=1:app.N_Knot;
            h=app.Solenoid_Length/app.N_Coil;
            if (app.RadioButtonXZ.Value ==1)
                app.y1(i)=app.Ymin+(app.Ymax-app.Ymin)/(app.N_Knot-1)*(i-1);
                app.z1(i)=app.Zmin+(app.Zmax-app.Zmin)/(app.N_Knot-1)*(i-1);
                for k=1:app.N_Knot
                  for j=1:app.N_Knot
                    app.ax=app.Ax(app.X0,app.y1(j),app.z1(k),app.Radius,h,app.N_Coil,app.I);
                    app.ay=app.Ay(app.X0,app.y1(j),app.z1(k),app.Radius,h,app.N_Coil,app.I);
                    app.az=app.Az(app.X0,app.y1(j),app.z1(k),app.Radius,h,app.N_Coil,app.I);
                    app.a1(k,j)=(app.ax.^2+app.ay.^2+app.az.^2).^0.5;
                  end
                end
              end 
            
            % ВЫЧИСЛЕНИЕ ПРОЕКЦИИ ВЕКТОР-ПОТЕНЦИАЛА НА ПЛОСКОСТЬ XoZ
              if (app.RadioButtonYZ.Value ==1)
                app.x2(i)=app.Xmin+(app.Xmax-app.Xmin)/(app.N_Knot-1)*(i-1);
                app.z2(i)=app.Zmin+(app.Zmax-app.Zmin)/(app.N_Knot-1)*(i-1);
                for k=1:app.N_Knot
                  for i=1:app.N_Knot
                    app.ax=app.Ax(app.x2(i),app.Y0,app.z2(k),app.Radius,h,app.N_Coil,app.I);
                    app.ay=app.Ay(app.x2(i),app.Y0,app.z2(k),app.Radius,h,app.N_Coil,app.I);
                    app.az=app.Az(app.x2(i),app.Y0,app.z2(k),app.Radius,h,app.N_Coil,app.I);
                    app.a2(k,i)=(app.ax.^2+app.ay.^2+app.az.^2).^0.5;
                  end
                end
              end
              % ВЫЧИСЛЕНИЕ ВЫЧИСЛЕНИЕ ПРОЕКЦИИ ВЕКТОР-ПОТЕНЦИАЛА НА ПЛОСКОСТЬ XoY
               if (app.RadioButtonXY.Value == 1)
                app.x3(i)=app.Xmin+(app.Xmax-app.Xmin)/(app.N_Knot-1)*(i-1);
                app.y3(i)=app.Ymin+(app.Ymax-app.Ymin)/(app.N_Knot-1)*(i-1);
                for j=1:app.N_Knot
                  for i=1:app.N_Knot
                    app.ax=app.Ax(app.x3(i),app.y3(j),app.Z0,app.Radius,h,app.N_Coil,app.I);
                    app.ay=app.Ay(app.x3(i),app.y3(j),app.Z0,app.Radius,h,app.N_Coil,app.I);
                    app.az=app.Az(app.x3(i),app.y3(j),app.Z0,app.Radius,h,app.N_Coil,app.I);
                    app.a3(i,j)=(app.ax.^2+app.ay.^2+app.az.^2).^0.5;
                  end
                end
              end  
              % ВЫЧИСЛЕНИЕ ВЫЧИСЛЕНИЕ ВЕКТОР-ПОТЕНЦИАЛА В УЗЛАХ ТРЕХМЕРНОЙ СЕТКИ
              if (app.RadioButton3D.Value == 1)
                app.x4(i)=app.Xmin+(app.Xmax-app.Xmin)/(app.N_Knot-1)*(i-1);
                app.y4(i)=app.Ymin+(app.Ymax-app.Ymin)/(app.N_Knot-1)*(i-1);
                app.z4(i)=app.Zmin+(app.Zmax-app.Zmin)/(app.N_Knot-1)*(i-1);
                for i=1:app.N_Knot
                  for j=1:app.N_Knot
                    for k=1:app.N_Knot
                      app.ax(i,j,k)=app.Ax(app.x4(i),app.y4(j),app.z4(k),app.Radius,h,app.N_Coil,app.I);
                      app.ay(i,j,k)=app.Ay(app.x4(i),app.y4(j),app.z4(k),app.Radius,h,app.N_Coil,app.I);
                      app.az(i,j,k)=app.Az(app.x4(i),app.y4(j),app.z4(k),app.Radius,h,app.N_Coil,app.I);
                      app.a4(i,j,k)=(app.ax(i,j,k).^2+app.ay(i,j,k).^2+app.az(i,j,k).^2).^0.5;
                    end
                  end
                end
             end
            %set(handles.pushbutton3,'Enable','on');
            app.TextArea.Value = 'ВЕКТОР ПОСЧИТАН';
        end

        % Button pushed function: pushbutton4
        function pushbutton4Pushed(app, event)
           helpdlg('ПРОГРАММА ПРЕДНАЗНАЧЕНА ДЛЯ РАСЧЕТА И ВИЗУАЛИЗАЦИИ НАПРЯЖЕННОСТИ МАГНИТНОГО ПОЛЯ КАТУШКИ С ПОСТОЯННЫМ ТОКОМ. В ОТЛИЧИЕ ОТ ТРАДИЦИОННОГО ПОДХОДА, В КОТОРОМ СОЛЕНОИД ПРЕДСТАВЛЯЕТСЯ НАБОРОМ КРУГОВЫХ ТОКОВ, А ПОТОМУ СОСТАВЛЯЮЩАЯ МАГНИТНОГО ПОЛЯ В ПЛОСКОСТИ ПЕРПЕНДИКУЛЯРНОЙ ОСИ OZ ОКАЗЫВАЕТСЯ РАВНОЙ НУЛЮ, В ПРОГРАММЕ ИСПОЛЬЗУЮТСЯ РАСЧЕТНЫЕ ФОРМУЛЫ, ПОЛУЧЕННЫЕ ПРИ РАССМОТРЕНИИ СОЛЕНОИДА В ВИДЕ СПИРАЛИ. (СМ.: ПОРШНЕВ С.В., ХАРИТОНОВ В.И. ОСОБЕННОСТИ МАГНИТНОГО ПОЛЯ СОЛЕНОИДА С ПОСТОЯННЫМ ТОКОМ// ЭЛЕКТРИЧЕСТВО. 1998. Т. 6. С. 71-75.) ПРОГРАММА ПОЗВОЛЯЕТ: 1) ВЫЧИСЛЯТЬ ПРОЕКЦИИ НАПРЯЖЕННОСТИ МАГНИТНОГО ПОЛЯ НА СООТВЕТСТВУЮЩИЕ КООРДИНАТНЫЕ ОСИ И СТРОИТЬ НОРМИРОВАННЫЕ ВЕКТОРНЫЕ ПОЛЯ, ДАЮЩИЕ ПРЕДСТАВЛЕНИЕ О НАПРЯЖЕННОСТИ И ГЕОМЕТРИИ СИЛОВЫХ ЛИНИЙ МАГНИТНОГО ПОЛЯ; 2) ВЫЧИСЛЯТЬ И ПРЕДСТАВЛЯТЬ ЗАВИСИМОСТИ МОДУЛЯ ПРОЕКЦИИ НАПРЯЖЕННОСТИ МАГНИТНОГО ПОЛЯ НА ПЛОСКОСТИ, ПЕРПЕНДИКУЛЯРНЫЕ ОСИ ОZ, ОТ РАССТОЯНИЯ ДО ОСИ OZ; 3) ВЫЧИСЛЯТЬ И ПРЕДСТАВЛЯТЬ НА ГРАФИКЕ МОДУЛЬ ОТНОШЕНИЯ НАПРЯЖЕННОСТЕЙ ПРОЕКЦИИ МАГНИТНОГО ПОЛЯ НА ПЛОСКОСТЬ, ПЕРПЕНДИКУЛЯРНУЮ ОСИ ОZ, К ПРОЕКЦИИ НАПРЯЖЕННОСТИ НА ОСЬ ОZ, ХАРАКТЕРИЗУЮЩЕГО СТЕПЕНЬ НЕПАРАЛЕЛЬНОСТИ СИЛОВОЙ ЛИНИИ ОСИ OZ.','КРАТКАЯ ИНФОРМАЦИЯ О ПРОГРАММЕ');
        end

        % Button pushed function: pushbutton7
        function pushbutton7Pushed(app, event)
            app.readData();
            app.Mode=1;
            set(app.pushbutton9,'Enable','off');
            set(app.pushbutton10,'Enable','off');
            X1=app.Mod_R*cos(app.Phi);Y1=app.Mod_R*sin(app.Phi);
            dx=app.dR*cos(app.Phi);dy=app.dR*sin(app.Phi);h=app.Solenoid_Length/app.N_Coil;
            for i=1:app.N_Step
              x1(i)=X1+app.dR*cos(app.Phi)*(i-1);
              app.y1(i)=Y1+app.dR*sin(app.Phi)*(i-1);
              Hx(i)=app.Bx(x1(i),app.y1(i),app.Z0,app.Radius,h,app.N_Coil,app.I);
              Hy(i)=app.By(x1(i),app.y1(i),app.Z0,app.Radius,h,app.N_Coil,app.I);
            end
            app.R=(x1.^2+app.y1.^2).^0.5;
            app.Mh=(Hx.^2+Hy.^2).^0.5;
            app.Mh=app.Mh/app.Mh(1);
            set(app.pushbutton9,'Enable','on');
            set(app.pushbutton10,'Enable','on');
        end

        % Button pushed function: pushbutton8
        function pushbutton8Pushed(app, event)
app.readData();
app.Mode=2;
set(app.pushbutton9,'Enable','off');
set(app.pushbutton10,'Enable','off');
X1=app.Mod_R*cos(app.Phi);Y1=app.Mod_R*sin(app.Phi);
dx=app.dR*cos(app.Phi);dy=app.dR*sin(app.Phi);h=app.Solenoid_Length/app.N_Coil;
for i=1:app.N_Step
  x1(i)=X1+app.dR*cos(app.Phi)*(i-1);
  app.y1(i)=Y1+app.dR*sin(app.Phi)*(i-1);
  Hx(i)=app.Bx(x1(i),app.y1(i),app.Z0,app.Radius,h,app.N_Coil,app.I);
  Hy(i)=app.By(x1(i),app.y1(i),app.Z0,app.Radius,h,app.N_Coil,app.I);
  Hz(i)=app.Bz(x1(i),app.y1(i),app.Z0,app.Radius,h,app.N_Coil,app.I);
end
app.R=(x1.^2+app.y1.^2).^0.5;
app.Mh=(Hx.^2+Hy.^2).^0.5;
for i=1:app.N_Step
  app.Mh(i)=app.Mh(i)/abs(Hz(i));
end
set(app.pushbutton9,'Enable','on');
set(app.pushbutton10,'Enable','on');
        end

        % Button pushed function: pushbutton5
        function pushbutton5Pushed(app, event)
            delete(app.UIFigure);
        end

        % Callback function
        function ReadDataButton_2Pushed(app, event)

        end

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            
            app.readData();
            app.TextArea.Value = 'Данные считаны';
            
        
        end

        % Button pushed function: pushbutton9
        function pushbutton9ButtonPushed(app, event)
            if app.Mode==1 
                %set(handles.text34,'String',strcat('МОДУЛЬ ПРОЕКЦИИ НАПРЯЖЕННОСТИ НА ПЛОСКОСТЬ Z=',mat2str(Z0),', НОРМИРОВАННЫЙ НА ЗНАЧЕНИЕ НАПРЯЖЕННОСТИ В ПЕРВОЙ ТОЧКЕ'));
                app.TextArea.Value = sprintf("МОДУЛЬ ПРОЕКЦИИ НАПРЯЖЕННОСТИ НА ПЛОСКОСТЬ Z= %1.4f , НОРМИРОВАННЫЙ НА ЗНАЧЕНИЕ НАПРЯЖЕННОСТИ В ПЕРВОЙ ТОЧКЕ",mat2str(app.Z0));
            end
            if app.Mode==2
              %set(handles.text34,'String',strcat('АБСОЛЮТНОЕ ЗНАЧЕНИЕ ОТНОШЕНИЯ ПРОЕКЦИИ НАПРЯЖЕННОСТИ НА ПЛОСКОСТЬ Z=',mat2str(Z0),' К ПРОЕКЦИИ НАПРЯЖЕННОСТИ НА ОСЬ OZ'));
              app.TextArea.Value = sprintf("АБСОЛЮТНОЕ ЗНАЧЕНИЕ ОТНОШЕНИЯ ПРОЕКЦИИ НАПРЯЖЕННОСТИ НА ПЛОСКОСТЬ Z= %1.4f , К ПРОЕКЦИИ НАПРЯЖЕННОСТИ НА ОСЬ OZ",mat2str(app.Z0));
            end  
            plot(app.UIAxes,app.R,app.Mh);
            app.UIAxes.XAxis.FontSize = 20; % Только ось X
            app.UIAxes.YAxis.FontSize = 20;
        end

        % Button pushed function: pushbutton10
        function pushbutton10ButtonPushed(app, event)
            hold on
                plot(app.R,app.Mh);
            hold off
        end

        % Button pushed function: pushbutton1
        function pushbutton1ButtonPushed(app, event)
                app.readData();
              i=1:app.N_Knot+1;
              if (app.RadioButtonXZ.Value ==1)
                
                app.TextArea.Value = sprintf("КООРДИНАТНАЯ СЕТКА В ПЛОСКОСТИ X0= %.1f",mat2str(app.X0));
                y(i)=app.Ymin+(app.Ymax-app.Ymin)/app.N_Knot*(i-1);
                z(i)=app.Zmin+(app.Zmax-app.Zmin)/app.N_Knot*(i-1);
                X1=-app.Radius/2;Y1=0;
                X2=app.Radius/2;Y2=0;
                X3=app.Radius/2;Y3=app.Solenoid_Length;
                X4=-app.Radius/2;Y4=app.Solenoid_Length;
                h_Coil=plot(app.UIAxes,[X1,X2,X3,X4,X1],[Y1,Y2,Y3,Y4,Y1]);
                hold on
                for i=1:app.N_Knot+1
                  for j=1:app.N_Knot+1
                    h_Knot=line(app.UIAxes,[y(i),y(i)],[z(j),z(j)],'Color','Red','Marker','o','MarkerSize',3);
                  end
                end
                hold off
              end 
              if (app.RadioButtonYZ.Value ==1)
               
                app.TextArea.Value = sprintf("КООРДИНАТНАЯ СЕТКА В ПЛОСКОСТИ Y0=%.1f",mat2str(app.Y0));
                x(i)=app.Xmin+(app.Xmax-app.Xmin)/app.N_Knot*(i-1);
                z(i)=app.Zmin+(app.Zmax-app.Zmin)/app.N_Knot*(i-1);
                X1=-app.Radius/2;Y1=0;
                X2=app.Radius/2;Y2=0;
                X3=app.Radius/2;Y3=app.Solenoid_Length;
                X4=-app.Radius/2;Y4=app.Solenoid_Length;
                h_coil=plot(app.UIAxes,[X1,X2,X3,X4,X1],[Y1,Y2,Y3,Y4,Y1]);
                hold on
                for i=1:app.N_Knot+1
                  for j=1:app.N_Knot+1
                    h_Knot=line(app.UIAxes,[x(i),x(i)],[z(j),z(j)],'Color','Red','Marker','o','MarkerSize',3);
                  end
                end
                hold off
              end 
              if (app.RadioButtonXY.Value ==1)
               
                app.TextArea.Value = sprintf('КООРДИНАТНАЯ СЕТКА В ПЛОСКОСТИ Z0= %.1f',mat2str(app.Z0));
                x(i)=app.Xmin+(app.Xmax-app.Xmin)/app.N_Knot*(i-1);
                y(i)=app.Ymin+(app.Ymax-app.Ymin)/app.N_Knot*(i-1);
                alpha=0:pi/100:2*pi;
                h_Coil=plot(app.UIAxes,app.Radius*cos(alpha),app.Radius*sin(alpha));
                hold on
                for i=1:app.N_Knot+1
                  for j=1:app.N_Knot+1
                    h_Knot=line(app.UIAxes,[x(i),x(i)],[y(j),y(j)],'Color','Red','Marker','o','MarkerSize',3);
                  end
                end
                hold off
              end 
              if (app.RadioButton3D.Value ==1)
                
                app.TextArea.Value = sprintf('ПРОСТРАНСТВЕННАЯ КООРДИНАТНАЯ СЕТКА');
                h=app.Solenoid_Length/app.N_Coil;phi=0:pi/50:app.N_Coil*2*pi;
                x1=app.Radius*sin(phi);app.y1=app.Radius*cos(phi);app.z1=phi/(2*pi)*h-app.Solenoid_Length/2;
                h_Coil=plot3(app.UIAxes,x1,app.y1,app.z1);
                view([30,30])
                x(i)=app.Xmin+(app.Xmax-app.Xmin)/app.N_Knot*(i-1);
                y(i)=app.Ymin+(app.Ymax-app.Ymin)/app.N_Knot*(i-1);
                z(i)=app.Zmin+(app.Zmax-app.Zmin)/app.N_Knot*(i-1);
                hold on
                for i=1:app.N_Knot+1
                  for j=1:app.N_Knot+1
                    for k=1:app.N_Knot+1
                      h_Knot=line(app.UIAxes,[x(i),x(i)],[y(j),y(j)],[z(k),z(k)],'Color','Red','Marker','o','MarkerSize',3);
                    end
                  end
                end
                axis square; grid on
                hold off
              end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {638, 638};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {239, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.pushbutton3 = uibutton(app.LeftPanel, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3Pushed, true);
            app.pushbutton3.Text = 'Визуализация силовых линий';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'Параметры соленоида';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'Параметры сетки';
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = 'Длина(см):';
            app.LengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LengthEditField.Limits = [0 Inf];
            app.LengthEditField.ValueDisplayFormat = '%.1f';
            app.LengthEditField.Value = 6;
            app.EditField_2Label = uilabel(app.LeftPanel);
            app.EditField_2Label.Text = 'Радиус(см):';
            app.RadiusEditField = uieditfield(app.LeftPanel, 'text');
            app.RadiusEditField.Value = '0.5';
            app.Label_8 = uilabel(app.LeftPanel);
            app.Label_8.Text = 'Число витков:';
            app.NCoilEditField = uieditfield(app.LeftPanel, 'text');
            app.NCoilEditField.Value = '200';
            app.Label_9 = uilabel(app.LeftPanel);
            app.Label_9.Text = 'Сила тока(А):';
            app.CurrentEditField = uieditfield(app.LeftPanel, 'text');
            app.CurrentEditField.Value = '1.0';
            app.XmaxEditFieldLabel = uilabel(app.LeftPanel);
            app.XmaxEditFieldLabel.Text = 'Xmax(см):';
            app.XmaxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.XmaxEditField.ValueDisplayFormat = '%.1f';
            app.XmaxEditField.Value = 5;
            app.XminEditFieldLabel = uilabel(app.LeftPanel);
            app.XminEditFieldLabel.Text = 'Xmin(см):';
            app.XminEditField = uieditfield(app.LeftPanel, 'text');
            app.XminEditField.Value = '-5.0';
            app.YminEditFieldLabel = uilabel(app.LeftPanel);
            app.YminEditFieldLabel.Text = 'Ymin(см):';
            app.YminEditField = uieditfield(app.LeftPanel, 'text');
            app.YminEditField.Value = '-5.0';
            app.YmaxEditFieldLabel = uilabel(app.LeftPanel);
            app.YmaxEditFieldLabel.Text = 'Ymax(см):';
            app.YmaxEditField = uieditfield(app.LeftPanel, 'text');
            app.YmaxEditField.Value = '5.0';
            app.ZmaxEditFieldLabel = uilabel(app.LeftPanel);
            app.ZmaxEditFieldLabel.Text = 'Zmax(см):';
            app.ZmaxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ZmaxEditField.ValueDisplayFormat = '%.1f';
            app.ZmaxEditField.Value = 8;
            app.ZminEditFieldLabel = uilabel(app.LeftPanel);
            app.ZminEditFieldLabel.Text = 'Zmin(см):';
            app.ZminEditField = uieditfield(app.LeftPanel, 'text');
            app.ZminEditField.Value = '-5.0';
            app.Label_10 = uilabel(app.LeftPanel);
            app.Label_10.Text = 'Число узлов:';
            app.N_KnotEditField = uieditfield(app.LeftPanel, 'text');
            app.N_KnotEditField.Value = '17';
            app.X0EditFieldLabel = uilabel(app.LeftPanel);
            app.X0EditFieldLabel.Text = 'X0(см):';
            app.X0EditField = uieditfield(app.LeftPanel, 'text');
            app.X0EditField.Value = '0.0';
            app.Y0EditFieldLabel = uilabel(app.LeftPanel);
            app.Y0EditFieldLabel.Text = 'Y0(см):';
            app.Y0EditField = uieditfield(app.LeftPanel, 'text');
            app.Y0EditField.Value = '0.0';
            app.Z0EditFieldLabel = uilabel(app.LeftPanel);
            app.Z0EditFieldLabel.Text = 'Z0(см):';
            app.Z0EditField = uieditfield(app.LeftPanel, 'text');
            app.Z0EditField.Value = '0.0';
            app.Label_11 = uilabel(app.LeftPanel);
            app.Label_11.Text = 'Расположение сетки';
            app.ViewButtonGroup = uibuttongroup(app.LeftPanel);
            app.RadioButtonXZ = uiradiobutton(app.ViewButtonGroup);
            app.RadioButtonXZ.Tag = 'RadioButtonXZ';
            app.RadioButtonXZ.Text = 'В плоскости,перпендикулярной оси Ox';
            app.RadioButtonXZ.Value = true;
            app.RadioButtonYZ = uiradiobutton(app.ViewButtonGroup);
            app.RadioButtonYZ.Tag = 'RadioButtonYZ';
            app.RadioButtonYZ.Text = 'В плоскости,перпендикулярной оси Oy';
            app.RadioButtonXY = uiradiobutton(app.ViewButtonGroup);
            app.RadioButtonXY.Tag = 'RadioButtonXY';
            app.RadioButtonXY.Text = 'В плоскости,перпендикулярной оси Oz';
            app.RadioButton3D = uiradiobutton(app.ViewButtonGroup);
            app.RadioButton3D.Tag = 'RadioButton3D';
            app.RadioButton3D.Text = 'В пространстве';
            app.pushbutton1 = uibutton(app.LeftPanel, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1ButtonPushed, true);
            app.pushbutton1.Text = 'Показать сетку ';
            app.pushbutton2 = uibutton(app.LeftPanel, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2Pushed, true);
            app.pushbutton2.Text = 'Вычисление вектор потенциала';
            app.pushbutton5 = uibutton(app.LeftPanel, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5Pushed, true);
            app.pushbutton5.Text = 'Выход';
            app.pushbutton4 = uibutton(app.LeftPanel, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4Pushed, true);
            app.pushbutton4.Text = 'Справка';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-5 5];
            app.UIAxes.YLim = [-5 5];
            app.UIAxes.ZLim = [-5 5];
            app.X00Label = uilabel(app.RightPanel);
            app.X00Label.Text = 'Координатная сетка в плоскости X0 = 0';
            app.Label_12 = uilabel(app.RightPanel);
            app.Label_12.Text = 'Параметры радиус-вектора';
            app.EditField_7Label = uilabel(app.RightPanel);
            app.EditField_7Label.Text = 'Начальная длина радиус вектора(см):';
            app.ModREditField = uieditfield(app.RightPanel, 'text');
            app.ModREditField.Value = '1.0';
            app.Label_13 = uilabel(app.RightPanel);
            app.Label_13.Text = 'Направление радиус ветора(град):';
            app.PhiEditField = uieditfield(app.RightPanel, 'text');
            app.PhiEditField.Value = '0.0';
            app.Label_14 = uilabel(app.RightPanel);
            app.Label_14.Text = 'Праращение длины радиус вектора(см):';
            app.dREditField = uieditfield(app.RightPanel, 'text');
            app.dREditField.Value = '0.1';
            app.Label_15 = uilabel(app.RightPanel);
            app.Label_15.Text = 'Число приращений радиус вектора:';
            app.NStepEditField = uieditfield(app.RightPanel, 'text');
            app.NStepEditField.Value = '10';
            app.pushbutton7 = uibutton(app.RightPanel, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @pushbutton7Pushed, true);
            app.pushbutton7.Text = 'Вычисление напряженности в плоскости,перпендикулярной оси Oz';
            app.pushbutton8 = uibutton(app.RightPanel, 'push');
            app.pushbutton8.ButtonPushedFcn = createCallbackFcn(app, @pushbutton8Pushed, true);
            app.pushbutton8.Text = 'Вычисление отношение напряженностей';
            app.TextAreaLabel = uilabel(app.RightPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.RightPanel);
            app.pushbutton9 = uibutton(app.RightPanel, 'push');
            app.pushbutton9.ButtonPushedFcn = createCallbackFcn(app, @pushbutton9ButtonPushed, true);
            app.pushbutton9.Text = 'Новый график';
            app.pushbutton10 = uibutton(app.RightPanel, 'push');
            app.pushbutton10.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10ButtonPushed, true);
            app.pushbutton10.Text = 'Наложить график';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'pinki.png');
            app.Image2 = uiimage(app.RightPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'fluttershy.png');
            app.ReadDataButton = uibutton(app.RightPanel, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'прочитать фигни';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testappdesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end